classdef userApplicationSOL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConnectWindowPanel             matlab.ui.container.Panel
        UploadAutonomousMissionButton  matlab.ui.control.Button
        PlanAutonomousMissionButton    matlab.ui.control.Button
        SensorOutputTextArea           matlab.ui.control.TextArea
        SensorOutputTextAreaLabel      matlab.ui.control.Label
        AvailablePortsDropDown         matlab.ui.control.DropDown
        AvailablePortsDropDownLabel    matlab.ui.control.Label
        EnterInstrumentUserInterfaceButton  matlab.ui.control.Button
        DisarmButton                   matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        RoutinesPanel                  matlab.ui.container.Panel
        AutoRunButton                  matlab.ui.control.Button
        CalibrateButton                matlab.ui.control.Button
        fileLabel                      matlab.ui.control.Label
        EventLogPanel                  matlab.ui.container.Panel
        Event1Button                   matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        AxisAdjustmentPanel            matlab.ui.container.Panel
        AutoCheckBox                   matlab.ui.control.CheckBox
        XScaleSpinner                  matlab.ui.control.Spinner
        XScaleSpinnerLabel             matlab.ui.control.Label
        YScaleSpinner                  matlab.ui.control.Spinner
        YScaleSpinnerLabel             matlab.ui.control.Label
        PumpControlPanel               matlab.ui.container.Panel
        H2O2CheckBox                   matlab.ui.control.CheckBox
        SOTSVolumeLabel                matlab.ui.control.Label
        SOTSDispensedLabel             matlab.ui.control.Label
        SODDispensedLabel              matlab.ui.control.Label
        SODVolumeLabel                 matlab.ui.control.Label
        BGSWDispensedLabel             matlab.ui.control.Label
        BGSWVolumeLabel                matlab.ui.control.Label
        MCLADispensedLabel             matlab.ui.control.Label
        MCLAVolumeLabel                matlab.ui.control.Label
        STOPALLButton                  matlab.ui.control.Button
        SOTSFlowSelect                 matlab.ui.control.Spinner
        mLminSpinnerLabel_5            matlab.ui.control.Label
        SODFlowSelect                  matlab.ui.control.Spinner
        mLminSpinnerLabel_4            matlab.ui.control.Label
        BGSWFlowSelect                 matlab.ui.control.Spinner
        mLminSpinnerLabel_3            matlab.ui.control.Label
        MCLAFlowSelect                 matlab.ui.control.Spinner
        mLminSpinnerLabel_2            matlab.ui.control.Label
        SampleFlowSelect               matlab.ui.control.Spinner
        mLminSpinnerLabel              matlab.ui.control.Label
        SOTSButton                     matlab.ui.control.Button
        SODButton                      matlab.ui.control.Button
        BGSWButton                     matlab.ui.control.Button
        MCLAButton                     matlab.ui.control.Button
        SampleButton                   matlab.ui.control.Button
    end

    
    %% Revision, V11, 01/25/2023
    %  revisions have not been well documented. A git repo was just created
    %  to keep up with changeds and have a soul source for the current app.
    %  -William Pardis
    %
    % added a while loop before data aquition to clear buffer so data stays
    % up to date
    % -William Pardis
    %% Revision, V10, 10/10/2019: 
    %  utilizing coordinated motion between multiple axes commmands from
    %  page 14 of the motor driver command set docutment
    %       added: stopAllPumps(app)
    
    properties (Access = public)
        % Define non-GUI app properties, all properties are accessed
        % through app.property
        
        file;       %data file name
      
        t=timer;    % timer object for acquiring and plotting data
        T1;         %
        ST;         %start time of app


        dx=30/86400;      %plot x axis parameter, used for scaling x window, in sec
        dt=30;

        sflow='0';        %value measured by the flow sensor
        
        log;              %event log flag
        logj=1;           %event log index
        
        routineStepNum = 0; % current step running, 0 = no routine running
        stepStartTime;      % time the step was started
        routineSteps;       % structure containing routine step information
                            % routineSteps(nstep).stepTime, routineSteps(nstep).motorStates = cell array of motor states {motor,on/off} 
        
        % for plotting                    
        x_vector=[];
        y_vector=[];
        Es_vector=[];
        Ex_vector=[];

        %starting app logic
        connecting=false;
        connected=false;
        
        %mission planning
        currentDuration=0; %keeping track of time
        mFile;
        mFileCreated=false;


        % HARDWARD VARIABLES
        SOLARIS;     %instrument serial object
        
        %Individual pump objects, these contain parameters such as on/off,
        %direction and speed
        Spump;      %Sample pump object      
        MCLApump;   %MCLA pump object      
        BGSWpump;   %BGSW pump object   0
        SODpump;    %SOD pump object   
        SOTSpump;   %SOTS pump object   
        
        V1=0;V2=0;V3=0;V4=0;V5=0;   % reagent volume used, by flow integration 

    end
    
    
    methods (Access = private)
        
        %this is the main function that plots and logs data at set data rate
        function MainPlot(app)

        
            try
                
                %clearing serial buffer so we get the most up to date point
                %app.SOLARIS.NumBytesAvailable()
                while app.SOLARIS.NumBytesAvailable()>10
                    readline(app.SOLARIS);
                end
                %aquire data from pmt
                value = readline(app.SOLARIS)   %reading data
                %parse data by comma
                value = split(value,',')
                length(value);
                data  = str2num(value(2))
                if isnumeric(data) & length(value)==10
                    x=datetime('now','TimeZone','Etc/UTC',"Format","dd,MMM,uuuu,HH,mm,ss,SSS");  %time for x axis
                    
                    x_vector = app.x_vector;
                    y_vector = app.y_vector;
                    % 
                 

                    period = 8000;%app.dt

                    if(length(x_vector)<period)
                        x_vector = [x_vector x];
                        y_vector = [y_vector data];
                    elseif(length(x_vector)==period)
                        x_vector = [x_vector(2:end) x];
                        y_vector = [y_vector(2:end) data];
                    elseif(length(x_vector)>period)
                        x_vector = [x_vector((length(x_vector)-period):end) x];
                        y_vector = [y_vector((length(y_vector)-period):end) data];
                    end 
                    % 
                    app.x_vector = [x_vector];
                    app.y_vector = [y_vector];

                    %plot data
                    scatter(app.UIAxes,x_vector,y_vector,'filled','bo');
                    %hold(app.UIAxes,'on');
                    xlim(app.UIAxes,[x-app.dx/2 x])
                    
                    %interpolation values displaying measurement values
                    p= [31.770 30.250 28.820 27.450 26.160 24.940 23.770 22.670...
                        21.620 20.630 19.680 18.780 17.930 17.120 16.350 15.620...
                        14.930 14.260 13.630 13.040 12.470 11.920 11.410 10.910...
                        10.450 10.000 9.5750 9.1700 8.7840 8.4160 8.0640 7.7300...
                        7.4100 7.1060 6.8150 6.5380 6.2730 6.0200 5.7780 5.5480 5.3270];
                    v=0:40;
                    T=interp1(p,v,str2num(value(3))*10);
                    app.Panel.Title=strcat("PMT Counts: ",num2str(data),"  Time: ",datestr(x,'HH:MM:ss'));
                    app.PumpControlPanel.Title = strcat("Pump Control    ","  SOT'S Temp:   ",num2str(T,3));

                    % Handle auto routines
                    stepNum = app.routineStepNum;       % routine step number that is running
                    if stepNum > 0                      % check if a routine is running
                        stepTStart = app.stepStartTime; % time the step was started 
                        stepEtime = etime(clock,stepTStart);            % time since the step was started
                        stepTime = app.routineSteps(stepNum).stepTime;  % total time of the step
                        nRoutineSteps  = length(app.routineSteps);           % number of steps in the routine
                        if stepEtime >=  stepTime % if step is done move to next step
                            if stepNum == nRoutineSteps 
                                % clean up if the routine is finished
                                FinishRoutine(app)
                            else
                                % otherwise continue to the next step
                                BeginNextStep(app)
                            end
                        end

                    end


                    %aquiring pump states
                    F1=0;F2=0;F3=0;F4=0;F5=0;
                    
                    T0=datetime('now');
                    dur=minutes(T0-app.T1)
                    if(app.Spump.onState)
                        F1=app.SampleFlowSelect.Value;
                        app.V1 = F1*dur+app.V1;
                    end
                    if(app.MCLApump.onState)
                        F2=app.MCLAFlowSelect.Value;
                        app.V2 = F2*dur+app.V2;
                        app.MCLAVolumeLabel.Text = strcat(num2str(app.V2, '%.1f'),' mL');
                    end
                    if(app.BGSWpump.onState)
                        F3=app.BGSWFlowSelect.Value;
                        app.V3 = F3*dur+app.V3;
                        app.BGSWVolumeLabel.Text = strcat(num2str(app.V3, '%.1f'),' mL');
                    end
                    if(app.SODpump.onState)
                        F4=app.SODFlowSelect.Value;
                        app.V4 = F4*dur+app.V4;
                        app.SODVolumeLabel.Text = strcat(num2str(app.V4, '%.1f'),' mL');
                    end
                    if(app.SOTSpump.onState)
                        F5=app.SOTSFlowSelect.Value;
                        app.V5 = F5*dur+app.V5;
                        app.SOTSVolumeLabel.Text = strcat(num2str(app.V5, '%.1f'),' mL');
                    end
                    app.T1=datetime('now');
                    
                    Es_vector = app.Es_vector;  %download global event variables
                    Ex_vector = app.Ex_vector;  

                    %log data
                    dataFormat = '%s,%.0f,%.1f,%.1f,%.1f,%.1f,%.1f,%.1f,%.1f,%.1f,%.1f,%.1f,%.1f,%d,';
                    if(app.log) %event, appending event tag to data stream
                        
                        E=strcat("Event ",num2str(app.logj));   %add them to the queue
                        Es_vector = [Es_vector E];
                        Ex_vector = [Ex_vector x];
                        
                        app.Es_vector = Es_vector;  %upload in global variables
                        app.Ex_vector = Ex_vector; 

                        fprintf(app.file,strcat(dataFormat,'%s\n'),datestr(x,30),data,T,F1,F2,F3,F4,F5,app.V1,app.V2,app.V3,app.V4,app.V5,app.routineStepNum,E);
                        app.logj=app.logj+1;
                        app.Event1Button.Text=strcat("Event ",num2str(app.logj));
                        app.log=false;

                    else        %No event, log normally
                        fprintf(app.file,strcat(dataFormat,'\n'),datestr(x,30),data,T,F1,F2,F3,F4,F5,app.V1,app.V2,app.V3,app.V4,app.V5,app.routineStepNum);
                    end
                  
                    % add to plot 
                    for i=1:length(Ex_vector)
                        xline(app.UIAxes,Ex_vector(i),'r')
                        text(app.UIAxes,Ex_vector(i),app.UIAxes.YLim(2)*(1/8),Es_vector(i),'Color','red')
                    end
                end
            catch ME
                warning(ME.message)
                UIFigureCloseRequest(app);
            end
        end
        
        %flow calibration calculation, converts flowrate to associated pump
        %command value
        function velocity = flowConversion(app,flow,calFactor)
        
            velocity = flow*calFactor;
            velocity = round(velocity);
        
        end
        
        
        function SwitchPumpState(app,pumpName)
        % This function executes when a pump button is pushed
        
        [pumpObj, ~, ~] = GetPumpObjs(app,pumpName);
        
            % If the pump is off, turn it on and if it's off, turn it on
            if(~pumpObj.onState)
                SetPumpState(app,pumpName,1)
            else    
                SetPumpState(app,pumpName,0)
            end
        
        end
        
        function SetPumpState(app,pumpName,state)
        %This function turns a pump on or off
        
        [pumpObj, buttonObj, flowObj] = GetPumpObjs(app,pumpName);
        
            if state
                buttonObj.BackgroundColor='g'; % change button color to green
                C=strcat('/',pumpObj.pumpTag,pumpObj.dir,'R'); % construct the on command
            else    
                buttonObj.BackgroundColor='w';% change button color to white
                C=strcat('/',pumpObj.pumpTag,'TR'); % contrsuct the off command
            end
            
            C=strcat(C,'|')
            
            write(app.SOLARIS,C,'char');pause(0.1);           % send the commmand once
            write(app.SOLARIS,C,'char');pause(0.1);           % send the command again    
            display(C)
            pumpObj.onState = state;    % change the stored state of the pump
           
           UpdatePumpObjs(app,pumpName, pumpObj, buttonObj,flowObj)
        
        end
        
        function stopAllPumps(app)
        % This function stops all pumps
        
            C='/_TR';  % construct the on command
            C=strcat(C,'|');
            display(C)
            write(app.SOLARIS,C,'char');pause(0.1);                   % send the commmand once
            write(app.SOLARIS,C,'char');pause(0.1);                   % send the command again
            
            app.SampleButton.BackgroundColor='w';% change button color to white
            app.MCLAButton.BackgroundColor='w';% change button color to white
            app.BGSWButton.BackgroundColor='w';% change button color to white
            app.SODButton.BackgroundColor='w';% change button color to white
            app.SOTSButton.BackgroundColor='w';% change button color to white
            
            app.Spump.onState    = false;
            app.MCLApump.onState = false;
            app.BGSWpump.onState = false;
            app.SODpump.onState  = false;
            app.SOTSpump.onState = false;
            
        end
 
        
        function ChangePumpSpeed(app,pumpName,flowRate)
        % This function executes when a pump button is pushed
        
        % get the pump objects for use below
        [pumpObj, buttonObj,flowObj] = GetPumpObjs(app,pumpName);
        
        if nargin < 3
            flowRate = flowObj.Value; % get the current speed value
        end
    
        % Record the new direction
        if(flowRate>0)
            pumpObj.dir = pumpObj.orientation{1}; % forward
        else
            pumpObj.dir = pumpObj.orientation{2}; % reverse
        end
        
        % convert the flow in mL/min to stepper counts
        value = flowConversion(app,flowRate,pumpObj.cal);
        
        % send the command to change the flow speed
        if(pumpObj.onState) % if the pump is on construct this command
            C=strcat('/',pumpObj.pumpTag,'V',num2str(abs(value)));
        else  % if the pump is off construct this command
            C=strcat('/',pumpObj.pumpTag,'V',num2str(abs(value)),'R');
        end
        
        C=strcat(C,'|');
        
        display(C)
        display(app.SOLARIS)
        write(app.SOLARIS,C,'char');pause(0.1);  % send the command
        write(app.SOLARIS,C,'char');pause(0.1)   % send the commmad again

        
        flowObj.Value = flowRate;      % Update the flow rate
        
        UpdatePumpObjs(app,pumpName, pumpObj, buttonObj,flowObj)
            
        
        end
        
        function [pumpObj, buttonObj, flowObj] = GetPumpObjs(app,pumpName)
        
            switch pumpName
                case 'Sample'
                    pumpObj = app.Spump; 
                    buttonObj = app.SampleButton;
                    flowObj = app.SampleFlowSelect;
                case 'MCLA'
                    pumpObj = app.MCLApump; 
                    buttonObj = app.MCLAButton;
                    flowObj = app.MCLAFlowSelect;
                case 'BGSW'
                    pumpObj = app.BGSWpump; 
                    buttonObj = app.BGSWButton;
                    flowObj = app.BGSWFlowSelect;
                case 'SOD'
                    pumpObj = app.SODpump; 
                    buttonObj = app.SODButton;
                    flowObj = app.SODFlowSelect;
                case 'SOTS'
                    pumpObj = app.SOTSpump; 
                    buttonObj = app.SOTSButton;
                    flowObj = app.SOTSFlowSelect;
            end
        
        end
    
        function  UpdatePumpObjs(app,pumpName, pumpObj, buttonObj,flowObj)
       % This function updates pump objects
        
            switch pumpName
                case 'Sample'
                    app.Spump = pumpObj; 
                    app.SampleButton = buttonObj;
                    app.SampleFlowSelect = flowObj;
                case 'MCLA'
                    app.MCLApump = pumpObj; 
                    app.MCLAButton = buttonObj;
                    app.MCLAFlowSelect = flowObj;
                case 'BGSW'
                    app.BGSWpump = pumpObj ; 
                    app.BGSWButton = buttonObj;
                    app.BGSWFlowSelect = flowObj;
                case 'SOD'
                    app.SODpump = pumpObj; 
                    app.SODButton = buttonObj;
                    app.SODFlowSelect = flowObj;
                case 'SOTS'
                    app.SOTSpump = pumpObj; 
                    app.SOTSButton = buttonObj;
                    app.SOTSFlowSelect = flowObj;
            end
            
        end
        
        function BeginNextStep(app)
            % This function starts the next step in a routine
            
            stepNum =  app.routineStepNum + 1; % Increment the step counter
            motorStates = app.routineSteps(stepNum).motorStates; % motor state array = [sample MCLA BGSW SOD SOTS]
            motorSpeeds = app.routineSteps(stepNum).motorSpeeds; % motor speed array = [sample MCLA BGSW SOD SOTS]
            
            % all motors off
            %stopAllPumps(app)
            
            % set the motor speeds for the step
            ChangePumpSpeed(app,'Sample',motorSpeeds(1));
            ChangePumpSpeed(app,'MCLA',motorSpeeds(2));
            ChangePumpSpeed(app,'BGSW',motorSpeeds(3));
            ChangePumpSpeed(app,'SOD',motorSpeeds(4));
            ChangePumpSpeed(app,'SOTS',motorSpeeds(5));
            
            
            % turn the motors on for the step
            if(~app.Spump.onState==motorStates(1))
                SetPumpState(app,'Sample',motorStates(1));
            end
            if(~app.MCLApump.onState==motorStates(2))
                SetPumpState(app,'MCLA',motorStates(2));
            end
            if(~app.BGSWpump.onState==motorStates(3))
                SetPumpState(app,'BGSW',motorStates(3));
            end
            if(~app.SODpump.onState==motorStates(4))
                SetPumpState(app,'SOD',motorStates(4));
            end
            if(~app.SOTSpump.onState==motorStates(5))
                SetPumpState(app,'SOTS',motorStates(5));
            end
            
            % record the step start time
            app.stepStartTime = clock;
            
            app.routineStepNum =  stepNum; % Increment the step counter
            
        end
        
        function FinishRoutine(app)
            
            % reset the routine variables
            app.routineStepNum = 0; 
            app.routineSteps = [];
            
            % Reset the button colors
            app.CalibrateButton.BackgroundColor = 'w';
            app.AutoRunButton.BackgroundColor = 'w';
            
            % Enable all buttons
            app.SampleButton.Enable = 1;
            app.MCLAButton.Enable = 1;
            app.BGSWButton.Enable = 1;
            app.SODButton.Enable = 1;
            app.SOTSButton.Enable = 1;
            app.AutoRunButton.Enable = 1;
            app.CalibrateButton.Enable = 1;
                     
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % This function runs when the app is first 
        
        
        %% pump calibration factors, converts flow speed entered to motor speed in steps/second
            app.Spump.cal = 9465;%updated FKt20230504 8320 %updated FKt20230428, 8000;
            app.MCLApump.cal = 8149;    % updated   FKt20230428,    8332; %updated FKt20230424, 7412; %updated FKt20230422 %8076; %updated 08232021
            app.BGSWpump.cal = 6429;    %                           7291; % updated FKt20230428, 8409; %updated FKt20230424 7992; %updated 08232021
            app.SODpump.cal = 8374;     %                           7704;     % updated FKt20230428, 8000;
            app.SOTSpump.cal = 8378;    %                           7040     % updated FKt20230428, 8000; %updated 08232021
            
            % define the forward direction of each pump
            % First value is forward direction, second value is negative direction
            app.Spump.orientation = {'D','P'};
            app.MCLApump.orientation = {'D','P'};
            app.BGSWpump.orientation = {'D','P'};
            app.SODpump.orientation = {'D','P'};
            app.SOTSpump.orientation = {'D','P'};
            
            % keep track of each pumps direction
            app.Spump.dir='D';                        
            app.MCLApump.dir = 'D';
            app.BGSWpump.dir = 'D';
            app.SODpump.dir = 'P';
            app.SOTSpump.dir = 'D';
            
            %Create a useful numerical ID for each pump
            app.Spump.pumpTag = '7';
            app.MCLApump.pumpTag = '5';
            app.BGSWpump.pumpTag = '2';
            app.SODpump.pumpTag = '1';
            app.SOTSpump.pumpTag = '4';


        drawnow
        app.AutoRunButton.Enable = false;
        app.CalibrateButton.Enable = false;
       
        
        try

            app.AvailablePortsDropDown.Items=serialportlist;
            
            while(app.connecting==false)
                pause(0.5)
            end
            
            app.SOLARIS.Timeout=60;
            
            txt={convertStringsToChars(readline(app.SOLARIS))};
            while(app.connected==false)
%                 if(length(txt)==10)
%                     txt={readline(app.SOLARIS)}
%                 end
                txt = [{convertStringsToChars(readline(app.SOLARIS))}; txt];
                app.SensorOutputTextArea.Value = txt;
                pause(0.5)
            end
            
            app.ST = datetime('now','TimeZone','Etc/UTC',"Format","ddMMMuuuu_HH:mm"); 
            
            %selecting and creating datalog file
            display('please select file & path...')
            [file,path,indx] = uiputfile(['data\' datestr(app.ST,30) '.txt']); 
            app.file = fopen(fullfile(path,file),'a');
            app.fileLabel.Text=strcat("logging to: ",path,file);
            display(strcat("logging to: ",datestr(app.ST,30),'.txt'))
            
            %hids
            app.ConnectWindowPanel.Visible=false;
            app.ConnectButton.Visible=false;
            app.AvailablePortsDropDown.Visible=false;
            app.AvailablePortsDropDownLabel.Visible=false;
            %app.AutonomousMissionPlanningPanel.Visible=false;
            %show
            app.PumpControlPanel.Visible=true;
            app.AxisAdjustmentPanel.Visible=true;
            app.Panel.Visible=true;
            app.RoutinesPanel.Visible=true;
            app.EventLogPanel.Visible=true;


            % Initialize instrument serial object
%             display('initializing COM port')
%             app.SOLARIS = serialport('COM26',9600);            % Com settings,SDSL: COM1 Rosette COM1, LenovoBench COM7, Tab1Bench COM 17, Tab2Bench COM5, Tab1Alvin COM15, Tab2Alvin COM
            flush(app.SOLARIS);
            
            pause(0.1);
            
%             % Initialize the pumps serial object
%             display('Initializing pump COM port')
%             waitbar(.3,hwait,'Initializing pump COM port');
%             app.mS = serial('COM5');            % Com settings,SDSL: COM2 Rosette COM1, LenovoBench COM5, Tab1Bench COM 16, Tab2Bench COM6, Tab1Alvin COM14,Tab2Alvin COM
%             set(app.mS,'BaudRate',9600);
%             set(app.mS,'Terminator','CR/LF');
%             display('Opening pump COM port')
%             waitbar(.4,hwait,'Opening pump COM port');
%             fopen(app.mS)                       % Open port
%             flushinput(app.mS);
%             waitbar(.45,hwait,'PMT COM port open')
%             display('Pump COM port open')
            
%             %% Set the PMT settings
%             %integration time (1 to 100)
%             waitbar(.5,hwait,'Sending PMT settings')
%             display('setting PMT integration time')
%             fprintf(app.SOLARIS,['P' char(10)]);
%             pause(0.1);display(char(fread(app.SOLARIS, 2)))
%             %number of readings (1 to 255)
%             display('setting PMT number of readings')
%             fprintf(app.SOLARIS,['R' char(1)]);
%             pause(0.1);display(char(fread(app.SOLARIS, 2)))
%             %default voltage 1000v
%             display('setting PMT voltage')
%             fprintf(app.SOLARIS,'D');
%             pause(0.1);display(char(fread(app.SOLARIS, 2)))
%             waitbar(1,hwait,'Sending PMT settings')
%             delete(hwait)


            % initilize pump state off
            app.Spump.onState = false;
            app.MCLApump.onState = false;
            app.BGSWpump.onState = false;
            app.SODpump.onState = false;
            app.SOTSpump.onState = false;
           
            
            % Initialize the routine variables
            app.routineStepNum = 0; % step number the routine is currently running
            app.routineSteps.stepTime = 0; 
            app.routineSteps.motorStates = [];
            app.routineSteps.motorSpeeds = [];
                       
            %% send default motor settings
            % Acceleration (L)
            a='5';    %motor acceleration value, 0 to 5000, 1 by default
            for i=1:5
                write(app.SOLARIS,strcat('/',num2str(i),'L',a,'R','|'),'string');
                flush(app.SOLARIS);   
                pause(0.1);
            end
            
            % Max Current (m)
            I='75';    %motor current value, 0 to 100, 50 by default
            for i=1:5
                write(app.SOLARIS,strcat('/',num2str(i),'m',I,'R','|'),'string');
                flush(app.SOLARIS);
                pause(0.1);
            end
            
            % Hold Current (h)
            I='1';    %motor hold current value, 0 to 50, 10 by default
            for i=1:5
                write(app.SOLARIS,strcat('/',num2str(i),'h',I,'R','|'),'string');
                flush(app.SOLARIS);
                pause(0.1);
            end
            
            % Make sure all pumps are off by sending off commands
            stopAllPumps(app)
            pause(0.1);
            
            % initilize pump speeds
            ChangePumpSpeed(app,'Sample',6.25)
            ChangePumpSpeed(app,'MCLA',6.25)
            ChangePumpSpeed(app,'BGSW',6.25)
            ChangePumpSpeed(app,'SOD',1.25)
            ChangePumpSpeed(app,'SOTS',1.5)
            
            %places grid on plot axis
            grid(app.UIAxes,'on');


            display('initializing timer...')
            % Initialie the timer for the main plotting routine
            app.t.TimerFcn = @(~,~) app.MainPlot();   %associate function with timer
            app.t.Period = 0.5;                 %data rate
            app.t.TasksToExecute = inf;         %do this forever
            app.t.ExecutionMode = 'fixedRate';  
            
            app.T1=datetime('now'); %time for flow integration
            
        catch ME
            warning(ME.message)
            msgbox(ME.message, 'Error','error');
            %fclose(instrfind);   % release all serial ports
            delete(instrfind)   % delete the serial port objects
            delete(app)         % Close the application
        end
        
        start(app.t);                       % start running the plot routine
        display('timer started')  
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        % This function executes when the application is closed
        
        
            % Stop all pumps
            stop(app.t)
            %stopAllPumps(app)
            %fclose(instrfind)   % release all serial ports
            delete(instrfind)   % delete the serial port objects
            delete(app)         % Close the application
            
            
        end

        % Button pushed function: SampleButton
        function SampleButtonPushed(app, event)
        % This function executes when the pump button is pushed
        
            SwitchPumpState(app,'Sample')
            
        end

        % Value changed function: SampleFlowSelect
        function SampleFlowSelectValueChanged(app, event)
        % This function executes when a flow rate value is changed
        
            ChangePumpSpeed(app,'Sample')
            
        end

        % Button pushed function: MCLAButton
        function MCLAButtonPushed(app, event)
           
            SwitchPumpState(app,'MCLA')   
            
        end

        % Value changed function: MCLAFlowSelect
        function MCLAFlowSelectValueChanged(app, event)
            
            ChangePumpSpeed(app,'MCLA')
            
        end

        % Button pushed function: BGSWButton
        function BGSWButtonPushed(app, event)
            
           SwitchPumpState(app,'BGSW')
            
        end

        % Value changed function: BGSWFlowSelect
        function BGSWFlowSelectValueChanged(app, event)
            
            ChangePumpSpeed(app,'BGSW')
                        
        end

        % Button pushed function: SODButton
        function SODButtonPushed(app, event)
            
            SwitchPumpState(app,'SOD')
            
        end

        % Value changed function: SODFlowSelect
        function SODFlowSelectValueChanged(app, event)
  
            ChangePumpSpeed(app,'SOD')
            
        end

        % Button pushed function: SOTSButton
        function SOTSButtonPushed(app, event)
            
            SwitchPumpState(app,'SOTS')
            
        end

        % Value changed function: SOTSFlowSelect
        function SOTSFlowSelectValueChanged(app, event)
            
            ChangePumpSpeed(app,'SOTS')
            
        end

        % Button pushed function: STOPALLButton
        function STOPALLButtonPushed(app, event)
                
            % Turn all pumps off
            stopAllPumps(app)
            
            % reset the routine variables
            app.routineStepNum = 0;
            app.routineSteps = [];
            
            % Reset the button colors
            app.CalibrateButton.BackgroundColor = 'w';
            app.AutoRunButton.BackgroundColor = 'w';
            
            % Enable all buttons
            app.SampleButton.Enable = 1;
            app.MCLAButton.Enable = 1;
            app.BGSWButton.Enable = 1;
            app.SODButton.Enable = 1;
            app.SOTSButton.Enable = 1;
            app.AutoRunButton.Enable = 1;
            app.CalibrateButton.Enable = 1;
        end

        % Value changed function: YScaleSpinner
        function YScaleSpinnerValueChanged(app, event)
            
            value = app.YScaleSpinner.Value;
            ylim(app.UIAxes,[0 value]);
            
        end

        % Value changed function: XScaleSpinner
        function XScaleSpinnerValueChanged(app, event)
            
            value = app.XScaleSpinner.Value;
            app.dt = value;
            app.dx=value/86400;  %in minutes
            
        end

        % Button pushed function: Event1Button
        function Event1ButtonPushed(app, event)
        
            app.log=true;
                    
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            % Perform a calibration, motor states are [Sample, MCLA, BGSW, SOD, SOTS]
                       
            app.routineStepNum = 0;
            
            %fc = app.flowFactorSelect.Value;
            
            in=1;       %index for calibration step
            
            % Step 1, MCLA, BGSW for 60 seconds
            app.routineSteps(in).stepTime = 90; 
            app.routineSteps(in).motorStates = [0 1 1 0 0];
            app.routineSteps(in).motorSpeeds = [0 6.25 6.25 0 0];
            in=in+1;
            
            % Step 2, Sample, MCLA, SOD for 60 seconds
            app.routineSteps(in).stepTime = 120; 
            app.routineSteps(in).motorStates = [0 1 1 0 1];
            app.routineSteps(in).motorSpeeds = [0 6.25 4.75 0 1.5];
            in=in+1;
            
            % Step 3, Sample, MCLA, for 60 seconds
            app.routineSteps(in).stepTime = 30; 
            app.routineSteps(in).motorStates = [0 1 1 1 1];
            app.routineSteps(in).motorSpeeds = [0 6.25 4.75 1.25 1.5];
            in=in+1;
            
            % Step 4, Sample, MCLA, and SOTS for 60 seconds
            app.routineSteps(in).stepTime = 90; 
            app.routineSteps(in).motorStates = [0 1 1 0 0];
            app.routineSteps(in).motorSpeeds = [0 6.25 6.25 0 0];
            in=in+1;
            % 
            % % Step 5, Sample, MCLA, SOTS, and SOD for 60 seconds
            % app.routineSteps(in).stepTime = 60; 
            % app.routineSteps(in).motorStates = [1 1 0 1 1];
            % app.routineSteps(in).motorSpeeds = [3.8 5 0 1 1.2];
            % in=in+1;
            % 
            % % Step 6, Sample, MCLA, for 60 seconds
            % app.routineSteps(in).stepTime = 60; 
            % app.routineSteps(in).motorStates = [1 1 0 0 0];
            % app.routineSteps(in).motorSpeeds = [5 5 0 0 0];
            % in=in+1;
            % 
            % % Step 7, MCLA and BGSW for 60 seconds
            % app.routineSteps(in).stepTime = 60; 
            % app.routineSteps(in).motorStates = [0 1 1 0 0];
            % app.routineSteps(in).motorSpeeds = [0 5 5 0 0];
            % in=in+1;
            % 
            % % Step 7, MCLA and BGSW for 60 seconds
            % app.routineSteps(in).stepTime = 60; 
            % app.routineSteps(in).motorStates = [0 1 1 1 0];
            % app.routineSteps(in).motorSpeeds = [0 5 5 1 0];
            % in=in+1;
            % 
            % % Step 7, MCLA and BGSW for 60 seconds
            % app.routineSteps(in).stepTime = 60; 
            % app.routineSteps(in).motorStates = [0 1 1 0 0];
            % app.routineSteps(in).motorSpeeds = [0 5 5 0 0];
            % in=in+1;
            % 
            % % Step 8, MCLA, BGSW, and SOTS for 120 seconds
            % app.routineSteps(in).stepTime = 90; 
            % app.routineSteps(in).motorStates = [0 1 1 0 1];
            % app.routineSteps(in).motorSpeeds = [0 5 3.8 0 1.2];
            % in=in+1;
            % 
            % % Step 9, MCLA, BGSW, SOTS, and SOD for 60 seconds
            % app.routineSteps(in).stepTime = 60; 
            % app.routineSteps(in).motorStates = [0 1 1 1 1];
            % app.routineSteps(in).motorSpeeds = [0 5 3.8 1 1.2];
            % in=in+1;
            % 
            % % Step 10, MCLA and BGSW for 60 seconds
            % app.routineSteps(in).stepTime = 60; 
            % app.routineSteps(in).motorStates = [0 1 1 0 0];
            % app.routineSteps(in).motorSpeeds = [0 5 5 0 0];
            % in=in+1;
            % 
            % % Step 9, return to MCLA and Sample
            % app.routineSteps(in).stepTime = 1; 
            % app.routineSteps(in).motorStates = [1 1 0 0 0];
            % app.routineSteps(in).motorSpeeds = [5 5 0 0 0];
            % 
            % change the button color
            app.CalibrateButton.BackgroundColor='g';
            
            % disable all buttons except stop all and event
            app.SampleButton.Enable = 0;
            app.MCLAButton.Enable = 0;
            app.BGSWButton.Enable = 0;
            app.SODButton.Enable = 0;
            app.SOTSButton.Enable = 0;
            app.CalibrateButton.Enable = 0;
            app.AutoRunButton.Enable = 0;
            
                    
            % Start the first step in the routine
            BeginNextStep(app)
            
         
        end

        % Button pushed function: AutoRunButton
        function AutoRunButtonPushed(app, event)
            % Run periodic calibrations, motor states are [Sample, MCLA, BGSW, SOD, SOTS]
                       
            app.routineStepNum = 0;
            
            p=1;


            % Step 1, Run Sample alone for 60 seconds
            app.routineSteps(p).stepTime = 60; 
            app.routineSteps(p).motorStates = [1 0 0 0 0];
            app.routineSteps(p).motorSpeeds = [5 0 0 0 0];
            p=p+1;
            
            % Step 2, Run MCLA alone for 60 seconds
            app.routineSteps(p).stepTime = 60; 
            app.routineSteps(p).motorStates = [0 1 0 0 0];
            app.routineSteps(p).motorSpeeds = [0 5 0 0 0];
            p=p+1;
            
            % Step 3, Run MCLA and Sample for the rest of the 20 minutes
            app.routineSteps(p).stepTime = 60*16; 
            app.routineSteps(p).motorStates = [0 0 0 0 0];
            app.routineSteps(p).motorSpeeds = [0 0 0 0 0];
            p=p+1;
            
            % Step 4, MCLA and BGSW for 60 seconds
            app.routineSteps(p).stepTime = 60; 
            app.routineSteps(p).motorStates = [0 1 1 0 0];
            app.routineSteps(p).motorSpeeds = [0 5 5 0 0];
            p=p+1;
            
            % Step 5, MCLA, BGSW, and SOTS for 90 seconds
            app.routineSteps(p).stepTime = 90; 
            app.routineSteps(p).motorStates = [0 1 1 0 1];
            app.routineSteps(p).motorSpeeds = [0 5 3.8 0 1.2];
            p=p+1;
            
            % Step 6, MCLA, BGSW, SOTS, and SOD for 30 seconds
            app.routineSteps(p).stepTime = 30; 
            app.routineSteps(p).motorStates = [0 1 1 1 1];
            app.routineSteps(p).motorSpeeds = [0 5 3.8 1 1.2];
            p=p+1;
            
            % Step 7, MCLA and BGSW for 60 seconds
            app.routineSteps(p).stepTime = 60; 
            app.routineSteps(p).motorStates = [0 1 1 0 0];
            app.routineSteps(p).motorSpeeds = [0 5 5 0 0];
            
            % Repeat the steps above 100 times (~30 hours)
            app.routineSteps = repmat(app.routineSteps,1,100);
            
            % change the button color
            app.AutoRunButton.BackgroundColor='g';
            
            % d2isable all buttons except stop all and event
            app.SampleButton.Enable = 0;
            app.MCLAButton.Enable = 0;
            app.BGSWButton.Enable = 0;
            app.SODButton.Enable = 0;
            app.SOTSButton.Enable = 0;
            app.AutoRunButton.Enable = 0;
            app.CalibrateButton.Enable = 0;
            
                             
            % Start the first step in the routine
            BeginNextStep(app)
        end

        % Callback function
        function CreateAutoMissionButtonPushed(app, event)
            app.DialogApp = DialogAppExample(app,szvalue,cvalue);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            app.SOLARIS=serialport(app.AvailablePortsDropDown.Value,9600);
            app.connecting=true;
            app.DisarmButton.Enable=true;
            app.EnterInstrumentUserInterfaceButton.Enable=true;
            %app.EnterInstrumentUserInterfaceButton_2.Enable=true;
            app.UploadAutonomousMissionButton.Enable=true;
            
        end

        % Button pushed function: DisarmButton
        function DisarmButtonPushed(app, event)
        
            C='disarm';
            display(C)
            display(app.SOLARIS)
            write(app.SOLARIS,C,'char');pause(0.1);  % send the command
            
        end

        % Button pushed function: EnterInstrumentUserInterfaceButton
        function EnterInstrumentUserInterfaceButtonPushed(app, event)
            
            C='disarm';
            display(C)
            write(app.SOLARIS,C,'char');pause(0.1);  % send the command
            app.connected=true;
            
        end

        % Button pushed function: PlanAutonomousMissionButton
        function PlanAutonomousMissionButtonPushed(app, event)
            
            app.ConnectWindowPanel.Visible=false;
            app.AutonomousMissionPlanningPanel.Visible=true;
            
        end

        % Callback function
        function EnterInstrumentUserInterfaceButton_2Pushed(app, event)
            
            app.connected=true;
            
        end

        % Callback function
        function ConnectToInstrumentButtonPushed(app, event)
            
            app.AutonomousMissionPlanningPanel.Visible=false;
            app.ConnectWindowPanel.Visible=true;
            
        end

        % Callback function
        function CreateButtonPushed(app, event)
            
            if(~app.mFileCreated)
                
                N=datestr(datetime('now','TimeZone','Etc/UTC',"Format","ddMMMuuuu_HH:mm"),30);
                [file,path,indx] = uiputfile(['data\m_' N '.txt']); 
                app.mFile = fopen(fullfile(path,file),'a');
                app.fileLabel.Text=strcat("logging to: m_",N,'.txt');
                
                app.mFileCreated=true;
                
            end
            
            sVal = app.SampleSpinner.Value;
            mVal = app.MCLASpinner.Value;
            bVal = app.BGSWSpinner.Value;
            sodVal = app.SODSpinner.Value;
            sotVal = app.SOTSSpinner.Value;
            
            X    = [app.currentDuration, app.currentDuration+app.TimeDurationminSpinner.Value];
            sY   = [sVal, sVal];
            mY   = [mVal, mVal];
            bY   = [bVal, bVal];
            sodY = [sodVal, sodVal];
            sotY = [sotVal, sotVal];
            
            app.AutoPlotPanel.AutoResizeChildren = 'off';
            ax1 = subplot(5,1,1,'Parent',app.AutoPlotPanel);hold(ax1,'on')
            ax2 = subplot(5,1,2,'Parent',app.AutoPlotPanel);hold(ax2,'on')
            ax3 = subplot(5,1,3,'Parent',app.AutoPlotPanel);hold(ax3,'on')
            ax4 = subplot(5,1,4,'Parent',app.AutoPlotPanel);hold(ax4,'on')
            ax5 = subplot(5,1,5,'Parent',app.AutoPlotPanel);hold(ax5,'on')
            area(ax1,X,sY,'FaceColor','b')
                ylabel(ax1,{'Sample'; '[^{mL}/_{min}]'})
                ylim(ax1,[0 10])
            area(ax2,X,mY,'FaceColor','r')
                ylabel(ax2,{'MCLA'; '[^{mL}/_{min}]'})
                ylim(ax2,[0 10])
            area(ax3,X,bY,'FaceColor','g')
                ylabel(ax3,{'BGSW'; '[^{mL}/_{min}]'})
                ylim(ax3,[0 10])
            area(ax4,X,sodY,'FaceColor','k')
                ylabel(ax4,{'SOD'; '[^{mL}/_{min}]'})
                ylim(ax4,[0 10])
            area(ax5,X,sotY,'FaceColor','m')
                ylabel(ax5,{'SOTS'; '[^{mL}/_{min}]'})
                xlabel(ax5,'minutes')
                ylim(ax5,[0 10])
                
            app.currentDuration=app.currentDuration+app.TimeDurationminSpinner.Value;
            
            line="";
            
            %sample pump
            tag = app.Spump.pumpTag;
            cal = app.Spump.cal;
            if(sVal==0)
                line=strcat(line,'/',tag,'TR,');                
            else
                line=strcat(line,'/',tag,'PR,/',tag,'V',num2str(flowConversion(app,sVal,cal)),'R,');
            end
            
            %mcla pump
            tag = app.MCLApump.pumpTag;
            cal = app.MCLApump.cal;
            if(mVal==0)
                line=strcat(line,'/',tag,'TR,');                
            else
                line=strcat(line,'/',tag,'PR,/',tag,'V',num2str(flowConversion(app,mVal,cal)),'R,');
            end
            
            %bgsw pump
            tag = app.BGSWpump.pumpTag;
            cal = app.BGSWpump.cal;
            if(bVal==0)
                line=strcat(line,'/',tag,'TR,');                
            else
                line=strcat(line,'/',tag,'PR,/',tag,'V',num2str(flowConversion(app,bVal,cal)),'R,');
            end
            
            %sod pump
            tag = app.SODpump.pumpTag;
            cal = app.SODpump.cal;
            if(sodVal==0)
                line=strcat(line,'/',tag,'TR,');                
            else
                line=strcat(line,'/',tag,'PR,/',tag,'V',num2str(flowConversion(app,sodVal,cal)),'R,');
            end
            
            %bgsw pump
            tag = app.SOTSpump.pumpTag;
            cal = app.SOTSpump.cal;
            if(sotVal==0)
                line=strcat(line,'/',tag,'TR,');                
            else
                line=strcat(line,'/',tag,'PR,/',tag,'V',num2str(flowConversion(app,sotVal,cal)),'R,');
            end
            
            line=strcat(line,num2str(app.TimeDurationminSpinner.Value*60),'\n');
            
            %display(line)
            fprintf(app.mFile,line)
            
        end

        % Button pushed function: UploadAutonomousMissionButton
        function UploadAutonomousMissionButtonPushed(app, event)
            
            C='upload';
            display(C)
            write(app.SOLARIS,C,'char');pause(2);  % send the command
            
            C='Y';
            write(app.SOLARIS,C,'char');
            
            [file,path,indx] = uigetfile;
            app.mFile = fopen(fullfile(path,file),'r');
            
            %# Get file size.
            fseek(app.mFile, 0, 'eof');
            fileSize = ftell(app.mFile);
            frewind(app.mFile);
            %# Read the whole file.
            data = fread(app.mFile, fileSize, 'uint8');
            %# Count number of line-feeds and increase by one.
            numLines = sum(data == 10) + 1
            
            fseek(app.mFile, 0, 'bof');
            
            for(i=1:numLines-1)
                C=fgetl(app.mFile);display(C)
                write(app.SOLARIS,C,'char');pause(1.5);
            end
            
            fclose(app.mFile);
            
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            value = app.AutoCheckBox.Value;
            if(value)
                ylim(app.UIAxes,'tickaligned');
            else
                app.YScaleSpinner.Value;
                ylim(app.UIAxes,[0 app.YScaleSpinner.Value]);
            end
        end

        % Value changed function: H2O2CheckBox
        function H2O2CheckBoxValueChanged(app, event)
            value = app.H2O2CheckBox.Value;
            if(value)
                app.MCLAButton.Text = 'AE-CL';
                app.BGSWButton.Text = 'CO3 buf';
                app.SODButton.Text = 'H2O2 cal';
                app.SOTSButton.Text = 'HCL';
                ChangePumpSpeed(app,'Sample',5)
                ChangePumpSpeed(app,'MCLA',5)
                ChangePumpSpeed(app,'BGSW',5)
                ChangePumpSpeed(app,'SOD',3)
                ChangePumpSpeed(app,'SOTS',1.25)
            else
                app.MCLAButton.Text = 'MCLA';
                app.BGSWButton.Text = 'BGSW';
                app.SODButton.Text = 'SOD';
                app.SOTSButton.Text = 'SOTS';
                ChangePumpSpeed(app,'Sample',6.25)
                ChangePumpSpeed(app,'MCLA',6.25)
                ChangePumpSpeed(app,'BGSW',6.25)
                ChangePumpSpeed(app,'SOD',1.25)
                ChangePumpSpeed(app,'SOTS',1.5)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PumpControlPanel = uipanel(app.UIFigure);
            app.PumpControlPanel.Title = 'Pump Control';
            app.PumpControlPanel.Visible = 'off';
            app.SampleButton = uibutton(app.PumpControlPanel, 'push');
            app.SampleButton.ButtonPushedFcn = createCallbackFcn(app, @SampleButtonPushed, true);
            app.SampleButton.Text = 'Sample';
            app.MCLAButton = uibutton(app.PumpControlPanel, 'push');
            app.MCLAButton.ButtonPushedFcn = createCallbackFcn(app, @MCLAButtonPushed, true);
            app.MCLAButton.Text = 'MCLA';
            app.BGSWButton = uibutton(app.PumpControlPanel, 'push');
            app.BGSWButton.ButtonPushedFcn = createCallbackFcn(app, @BGSWButtonPushed, true);
            app.BGSWButton.Text = 'BGSW';
            app.SODButton = uibutton(app.PumpControlPanel, 'push');
            app.SODButton.ButtonPushedFcn = createCallbackFcn(app, @SODButtonPushed, true);
            app.SODButton.Text = 'SOD';
            app.SOTSButton = uibutton(app.PumpControlPanel, 'push');
            app.SOTSButton.ButtonPushedFcn = createCallbackFcn(app, @SOTSButtonPushed, true);
            app.SOTSButton.Text = 'SOTS';
            app.mLminSpinnerLabel = uilabel(app.PumpControlPanel);
            app.mLminSpinnerLabel.Text = '[mL/min]';
            app.SampleFlowSelect = uispinner(app.PumpControlPanel);
            app.SampleFlowSelect.Step = 0.1;
            app.SampleFlowSelect.Limits = [-10 10];
            app.SampleFlowSelect.ValueDisplayFormat = '%.2f';
            app.SampleFlowSelect.ValueChangedFcn = createCallbackFcn(app, @SampleFlowSelectValueChanged, true);
            app.mLminSpinnerLabel_2 = uilabel(app.PumpControlPanel);
            app.mLminSpinnerLabel_2.Text = '[mL/min]';
            app.MCLAFlowSelect = uispinner(app.PumpControlPanel);
            app.MCLAFlowSelect.Step = 0.1;
            app.MCLAFlowSelect.Limits = [-30 30];
            app.MCLAFlowSelect.ValueDisplayFormat = '%.2f';
            app.MCLAFlowSelect.ValueChangedFcn = createCallbackFcn(app, @MCLAFlowSelectValueChanged, true);
            app.mLminSpinnerLabel_3 = uilabel(app.PumpControlPanel);
            app.mLminSpinnerLabel_3.Text = '[mL/min]';
            app.BGSWFlowSelect = uispinner(app.PumpControlPanel);
            app.BGSWFlowSelect.Step = 0.1;
            app.BGSWFlowSelect.Limits = [-30 30];
            app.BGSWFlowSelect.ValueDisplayFormat = '%.2f';
            app.BGSWFlowSelect.ValueChangedFcn = createCallbackFcn(app, @BGSWFlowSelectValueChanged, true);
            app.mLminSpinnerLabel_4 = uilabel(app.PumpControlPanel);
            app.mLminSpinnerLabel_4.Text = '[mL/min]';
            app.SODFlowSelect = uispinner(app.PumpControlPanel);
            app.SODFlowSelect.Step = 0.1;
            app.SODFlowSelect.Limits = [-5 5];
            app.SODFlowSelect.ValueDisplayFormat = '%.2f';
            app.SODFlowSelect.ValueChangedFcn = createCallbackFcn(app, @SODFlowSelectValueChanged, true);
            app.mLminSpinnerLabel_5 = uilabel(app.PumpControlPanel);
            app.mLminSpinnerLabel_5.Text = '[mL/min]';
            app.SOTSFlowSelect = uispinner(app.PumpControlPanel);
            app.SOTSFlowSelect.Step = 0.1;
            app.SOTSFlowSelect.Limits = [-10 10];
            app.SOTSFlowSelect.ValueDisplayFormat = '%.2f';
            app.SOTSFlowSelect.ValueChangedFcn = createCallbackFcn(app, @SOTSFlowSelectValueChanged, true);
            app.STOPALLButton = uibutton(app.PumpControlPanel, 'push');
            app.STOPALLButton.ButtonPushedFcn = createCallbackFcn(app, @STOPALLButtonPushed, true);
            app.STOPALLButton.Text = 'STOP ALL';
            app.MCLAVolumeLabel = uilabel(app.PumpControlPanel);
            app.MCLAVolumeLabel.Text = 'MCLA Volume';
            app.MCLADispensedLabel = uilabel(app.PumpControlPanel);
            app.MCLADispensedLabel.Text = 'MCLA Dispensed: ';
            app.BGSWVolumeLabel = uilabel(app.PumpControlPanel);
            app.BGSWVolumeLabel.Text = 'BGSW Volume';
            app.BGSWDispensedLabel = uilabel(app.PumpControlPanel);
            app.BGSWDispensedLabel.Text = 'BGSW Dispensed: ';
            app.SODVolumeLabel = uilabel(app.PumpControlPanel);
            app.SODVolumeLabel.Text = 'SOD Volume';
            app.SODDispensedLabel = uilabel(app.PumpControlPanel);
            app.SODDispensedLabel.Text = 'SOD Dispensed: ';
            app.SOTSDispensedLabel = uilabel(app.PumpControlPanel);
            app.SOTSDispensedLabel.Text = 'SOTS Dispensed: ';
            app.SOTSVolumeLabel = uilabel(app.PumpControlPanel);
            app.SOTSVolumeLabel.Text = 'SOTS Volume';
            app.H2O2CheckBox = uicheckbox(app.PumpControlPanel);
            app.H2O2CheckBox.ValueChangedFcn = createCallbackFcn(app, @H2O2CheckBoxValueChanged, true);
            app.H2O2CheckBox.Text = 'H2O2';
            app.AxisAdjustmentPanel = uipanel(app.UIFigure);
            app.AxisAdjustmentPanel.Title = 'Axis Adjustment';
            app.AxisAdjustmentPanel.Visible = 'off';
            app.YScaleSpinnerLabel = uilabel(app.AxisAdjustmentPanel);
            app.YScaleSpinnerLabel.Text = 'Y Scale';
            app.YScaleSpinner = uispinner(app.AxisAdjustmentPanel);
            app.YScaleSpinner.Step = 500;
            app.YScaleSpinner.Limits = [1 Inf];
            app.YScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @YScaleSpinnerValueChanged, true);
            app.YScaleSpinner.Value = 2000;
            app.XScaleSpinnerLabel = uilabel(app.AxisAdjustmentPanel);
            app.XScaleSpinnerLabel.Text = 'X Scale';
            app.XScaleSpinner = uispinner(app.AxisAdjustmentPanel);
            app.XScaleSpinner.Step = 25;
            app.XScaleSpinner.Limits = [1 Inf];
            app.XScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @XScaleSpinnerValueChanged, true);
            app.XScaleSpinner.Value = 100;
            app.AutoCheckBox = uicheckbox(app.AxisAdjustmentPanel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox.Value = true;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.Visible = 'off';
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Counts')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.EventLogPanel = uipanel(app.UIFigure);
            app.EventLogPanel.Title = 'Event Log';
            app.EventLogPanel.Visible = 'off';
            app.Event1Button = uibutton(app.EventLogPanel, 'push');
            app.Event1Button.ButtonPushedFcn = createCallbackFcn(app, @Event1ButtonPushed, true);
            app.Event1Button.Text = 'Event 1';
            app.fileLabel = uilabel(app.UIFigure);
            app.fileLabel.Text = 'file';
            app.RoutinesPanel = uipanel(app.UIFigure);
            app.RoutinesPanel.Title = 'Routines';
            app.RoutinesPanel.Visible = 'off';
            app.CalibrateButton = uibutton(app.RoutinesPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.AutoRunButton = uibutton(app.RoutinesPanel, 'push');
            app.AutoRunButton.ButtonPushedFcn = createCallbackFcn(app, @AutoRunButtonPushed, true);
            app.AutoRunButton.Text = 'Auto Run';
            app.ConnectWindowPanel = uipanel(app.UIFigure);
            app.ConnectWindowPanel.Title = 'Connect Window';
            app.ConnectButton = uibutton(app.ConnectWindowPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisarmButton = uibutton(app.ConnectWindowPanel, 'push');
            app.DisarmButton.ButtonPushedFcn = createCallbackFcn(app, @DisarmButtonPushed, true);
            app.DisarmButton.Enable = 'off';
            app.DisarmButton.Text = 'Disarm';
            app.EnterInstrumentUserInterfaceButton = uibutton(app.ConnectWindowPanel, 'push');
            app.EnterInstrumentUserInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @EnterInstrumentUserInterfaceButtonPushed, true);
            app.EnterInstrumentUserInterfaceButton.Enable = 'off';
            app.EnterInstrumentUserInterfaceButton.Text = 'Enter Instrument User Interface';
            app.AvailablePortsDropDownLabel = uilabel(app.ConnectWindowPanel);
            app.AvailablePortsDropDownLabel.Text = 'Available Ports';
            app.AvailablePortsDropDown = uidropdown(app.ConnectWindowPanel);
            app.SensorOutputTextAreaLabel = uilabel(app.ConnectWindowPanel);
            app.SensorOutputTextAreaLabel.Text = 'Sensor Output';
            app.SensorOutputTextArea = uitextarea(app.ConnectWindowPanel);
            app.PlanAutonomousMissionButton = uibutton(app.ConnectWindowPanel, 'push');
            app.PlanAutonomousMissionButton.ButtonPushedFcn = createCallbackFcn(app, @PlanAutonomousMissionButtonPushed, true);
            app.PlanAutonomousMissionButton.Text = 'Plan Autonomous Mission';
            app.UploadAutonomousMissionButton = uibutton(app.ConnectWindowPanel, 'push');
            app.UploadAutonomousMissionButton.ButtonPushedFcn = createCallbackFcn(app, @UploadAutonomousMissionButtonPushed, true);
            app.UploadAutonomousMissionButton.Enable = 'off';
            app.UploadAutonomousMissionButton.Text = 'Upload Autonomous Mission';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = userApplicationSOL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end